classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        STARTButton    matlab.ui.control.StateButton
        Switch         matlab.ui.control.Switch
        SwitchLabel    matlab.ui.control.Label
        Slider_6       matlab.ui.control.Slider
        Slider_6Label  matlab.ui.control.Label
        Slider_5       matlab.ui.control.Slider
        Slider_5Label  matlab.ui.control.Label
        Slider_4       matlab.ui.control.Slider
        Slider_4Label  matlab.ui.control.Label
        Slider_3       matlab.ui.control.Slider
        Slider_3Label  matlab.ui.control.Label
        Slider_2       matlab.ui.control.Slider
        Slider_2Label  matlab.ui.control.Label
        Slider         matlab.ui.control.Slider
        SliderLabel    matlab.ui.control.Label
        Button_2       matlab.ui.control.Button
        Button         matlab.ui.control.Button
    end

    
    properties (Access = private)
        robot % Description
    end
    
    methods (Access = private)
        
        function results = module_show(app)
            %% 模型導入
            clc;
            %%%%%%%%%%%%%%%%%%%%%%%%
            %% 建立機器人模型
                    % theta    d        a        alpha     offset
                    %L1 = Link([0 0.2 1 pi/4 0],'standard'); %標準模式 L1 = Link([0 0.2 1
                    %pi/4 0],'modify'); %改進模式
            % mass = [3.7000, 8.3930, 2.33, 1.2190, 1.2190, 0.1897];
            
            % center_of_mass = [
            %         0,-0.02561, 0.00193
            %         0.2125, 0, 0.11336
            %         0.15, 0, 0.0265
            %         0, -0.0018, 0.01634
            %         0, -0.0018, 0.01634
            %         0, 0, -0.001159];
            %不寫默認標準模式 % robot length values (metres)
            L1=Link([0       0.08916    0        pi/2      0     ],'standard'); 
            L2=Link([0       0        0.425     0         0     ],'standard');
            L3=Link([0       0        0.39225   0         0     ],'standard');
            L4=Link([0       0.10915   0        pi/2      0     ],'standard');
            L5=Link([0       0.09456    0        -pi/2     0     ],'standard');
            L6=Link([0       0.0823     0        0         0     ],'standard');
            app.robot = SerialLink([L1 L2 L3 L4 L5 L6],'name','tecoarm')%連接連桿，機器人命名
            
            app.robot.plot([0,0,0,0,0,0]);%輸出機器人模型，後面的六個角為輸出時的theta姿態
            app.robot.display();
            teach(app.robot);
            theta=[0,0,0,0,0,0];%指定關節角
            p=app.robot.fkine(theta)%fkine正解函數，根據我們給定的關節角theta，求解出末端位置p
            q=app.robot.ikine(p)%ikine逆解函數，根據我們給定的末端位置p，求解出關節角q
        end
        
        function results = jtraj(app)
            %******************%
            %      jtraj       %
            %******************%
            %% 角度 to 角度
            init_ang = [0 0 0 0 0 0];
            targ_ang = [pi/4, -pi/3, pi/5, pi/2, -pi/4, pi/6];
            step = 50;
            [q,qd,qdd] = jtraj(init_ang,targ_ang,step);                                %直接得到角度、角速度、角加速度的的序列
            %% 位置 to 位置
            % T1=transl(0.5,0,0);%根據給定始始點，得到起始點位置
            % T2=transl(0,0.5,0.5);%根據給定終止點，得到終止點位置
            % q1=robot.ikine(T1);%根據起始點位置，得到起始點關節角
            % q2=robot.ikine(T2);%根據終止點位置，得到終止點關節角
            % [q ,qd, qdd]=jtraj(q1,q2,50); %五次多項式軌跡，得到關節角度，角速度，角加速度，50為採樣數
        
            grid on
            T=app.robot.fkine(q);%根据插值，得到末端執行器的位置
            nT=T.T; plot3(squeeze(nT(1,4,:)),squeeze(nT(2,4,:)),squeeze(nT(3,4,:)));%輸出末端點軌跡
        
            zlim([-1.5 1.5])
            hold on
            app.robot.plot(q);%動畫展示
        end
        
        function results = ctraj(app)
            %******************%
            %      ctraj       %
            %******************%
            %參數設置
            init_ang = [0 0 0 0 0 0];
            targ_ang = [pi/4, -pi/3, pi/5, pi/2, -pi/4, pi/6];
            step = 50;
        
            T0 = app.robot.fkine(init_ang)
            T1 = app.robot.fkine(targ_ang)
        
            Tc = ctraj(T0,T1,step);                                                    %得到每一步的T陣列
            q = app.robot.ikine(Tc,'mask',[1 1 1 1 0 1]);
            grid on
            nT=Tc.T;
            plot3(squeeze(nT(1,4,:)),squeeze(nT(2,4,:)),squeeze(nT(3,4,:)));%輸出末端點軌跡
        
            zlim([-1.5 1.5])
            hold on
            app.robot.plot(q);%動畫展示
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
%             traj = value;
%             if (value == 1)
%                 traj = 'jtraj'
%             
%             elseif ( value == 0)
%                 traj = 'ctraj'
%             end
             switch lower(value)
                case 'off'
                    app.jtraj();
                case 'on'
                    app.ctraj();
            end
        end

        % Value changed function: STARTButton
        function STARTButtonValueChanged(app, event)
            value = app.STARTButton.Value;
            app.module_show();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = 'Slider';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_3Label = uilabel(app.UIFigure);
            app.Slider_3Label.Text = 'Slider';
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_4Label = uilabel(app.UIFigure);
            app.Slider_4Label.Text = 'Slider';
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_5Label = uilabel(app.UIFigure);
            app.Slider_5Label.Text = 'Slider';
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_6Label = uilabel(app.UIFigure);
            app.Slider_6Label.Text = 'Slider';
            app.Slider_6 = uislider(app.UIFigure);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.STARTButton = uibutton(app.UIFigure, 'state');
            app.STARTButton.ValueChangedFcn = createCallbackFcn(app, @STARTButtonValueChanged, true);
            app.STARTButton.Text = 'START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end